classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BeginningSliderLabel  matlab.ui.control.Label
        BeginningSlider       matlab.ui.control.Slider
        EndSliderLabel        matlab.ui.control.Label
        EndSlider             matlab.ui.control.Slider
        ChoosefolderButton    matlab.ui.control.Button
        AxesFullData          matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filenamesToRead % Description
        sFolderPath % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
            clc
            app.filenamesToRead = ["_TEL_HVBMS_CURR.txt", "_TEL_HVBMS_MAXCVOLT.txt", "_TEL_HVBMS_MINCVOLT.txt"]
            app.filenamesToRead
        end

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            if(exist(app.sFolderPath))
                app.sFolderPath=uigetdir(app.sFolderPath);
            else
                app.sFolderPath=uigetdir();
            end
            %sFolderPath=uigetdir()
            app.filenamesToRead
            app.filenamesToRead = ["_TEL_HVBMS_CURR.txt", "_TEL_HVBMS_MAXCVOLT.txt", "_TEL_HVBMS_MINCVOLT.txt"]
            [oTimestamps, times, val] = readSensorsInFolder(app.filenamesToRead, app.sFolderPath);
            plot(app.AxesFullData, times, val(:,1))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeginningSliderLabel = uilabel(app.UIFigure);
            app.BeginningSliderLabel.Text = 'Beginning';
            app.BeginningSlider = uislider(app.UIFigure);
            app.EndSliderLabel = uilabel(app.UIFigure);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.UIFigure);
            app.ChoosefolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.AxesFullData = uiaxes(app.UIFigure);
            title(app.AxesFullData, 'Title')
            xlabel(app.AxesFullData, 'X')
            ylabel(app.AxesFullData, 'Y')
            zlabel(app.AxesFullData, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end